classdef LAHacks2023App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Hyperlink                       matlab.ui.control.Hyperlink
        NitrogenCompositionLabel        matlab.ui.control.Label
        NitrogenPollutionThemostcommonnitrogencompoundsLabel  matlab.ui.control.Label
        BacktoCalculatorButton          matlab.ui.control.Button
        FactsAboutNitrogenPollutionButton  matlab.ui.control.Button
        FeedbackLabel                   matlab.ui.control.Label
        NitrogenRunOffCalculatorLabel   matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        outputLabel                     matlab.ui.control.Label
        CompositionNitrogenEditField_3  matlab.ui.control.NumericEditField
        CompositionNitrogenEditField_3Label  matlab.ui.control.Label
        PoundsofFertilizerUsedPerAcreEditField_2  matlab.ui.control.NumericEditField
        PoundsofFertilizerUsedPerAcreEditField_2Label  matlab.ui.control.Label
        SquareAcerageEditField_3        matlab.ui.control.NumericEditField
        SquareAcerageEditField_3Label   matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        Image4                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            acreage = app.SquareAcerageEditField_3.Value;
            lbsPerAcre = app.PoundsofFertilizerUsedPerAcreEditField_2.Value;
            compNitrogen = app.CompositionNitrogenEditField_3.Value;
            lbsNitrogen = lbsPerAcre * (compNitrogen/100);
            lbsRunOff = lbsNitrogen*.2;
            totalRunOff = lbsRunOff*acreage;
            holdTotal = int2str(totalRunOff);
            holdAcreage = int2str(acreage);
            holdLBS = int2str(lbsPerAcre);
            holdStr = strcat("The total pounds of runoff for using ", holdLBS, " pounds of fertilizer per acre on ", holdAcreage, " acres of land is ", holdTotal, " pounds");
            app.outputLabel.Visible = 'on';
            app.Hyperlink.Visible = "off";
            app.outputLabel.Text = holdStr;
            c =  newline;
            if lbsPerAcre > 50
                app.FeedbackLabel.Visible = "on";
                app.FeedbackLabel.Text = "You are using more fertilizer than neccessary" + c + "Try reducing your fertilizer waste by limiting the number of pounds used per acre to 50 or less";
            else
                app.FeedbackLabel.Visible = "on";
                app.FeedbackLabel.Text = "You are doing great job at using a proper amount of nitrogen" + c + "To continue learning how to improve fertilizer efficieny click the link below";
                app.Hyperlink.Visible = "on";
            end
                
        end

        % Button pushed function: FactsAboutNitrogenPollutionButton
        function FactsAboutNitrogenPollutionButtonPushed(app, event)
            app.FactsAboutNitrogenPollutionButton.Visible = 'off';
            app.CalculateButton.Visible = 'off';
            app.NitrogenCompositionLabel.Visible = "off";
            app.BacktoCalculatorButton.Visible = 'on';
            app.PoundsofFertilizerUsedPerAcreEditField_2.Visible = 'off';
            app.CompositionNitrogenEditField_3.Visible = 'off';
            app.SquareAcerageEditField_3.Visible = 'off';
            app.outputLabel.Visible = "off";
            app.Image.Visible = "off";
            app.Hyperlink.Visible = "off";
            app.FeedbackLabel.Visible = "off";
            app.Image2.Visible = "off";
            app.Image4.Visible = "on";
            app.NitrogenPollutionThemostcommonnitrogencompoundsLabel.Visible = "on";
            app.PoundsofFertilizerUsedPerAcreEditField_2Label.Visible = "off";
            app.CompositionNitrogenEditField_3Label.Visible = "off";
            app.SquareAcerageEditField_3Label.Visible = "off";
        end

        % Button pushed function: BacktoCalculatorButton
        function BacktoCalculatorButtonPushed(app, event)
            app.FactsAboutNitrogenPollutionButton.Visible = 'on';
            app.CalculateButton.Visible = 'on';
            app.BacktoCalculatorButton.Visible = 'off';
            app.PoundsofFertilizerUsedPerAcreEditField_2.Visible = 'on';
            app.CompositionNitrogenEditField_3.Visible = 'on';
            app.SquareAcerageEditField_3.Visible = 'on';
            app.outputLabel.Visible = "off";
            app.Image.Visible = "on";
            app.Image2.Visible = "on";
            app.Image4.Visible = "off";
            app.NitrogenCompositionLabel.Visible = "on";
            app.NitrogenPollutionThemostcommonnitrogencompoundsLabel.Visible = "off";
            app.PoundsofFertilizerUsedPerAcreEditField_2Label.Visible = "on";
            app.CompositionNitrogenEditField_3Label.Visible = "on";
            app.SquareAcerageEditField_3Label.Visible = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'LAHacksFertilizerPicture.jpeg');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.Visible = 'off';
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'LAHacks Water.png');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.SquareAcerageEditField_3Label = uilabel(app.UIFigure);
            app.SquareAcerageEditField_3Label.Text = 'Square Acerage';
            app.SquareAcerageEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.SquareAcerageEditField_3.Limits = [0 Inf];
            app.PoundsofFertilizerUsedPerAcreEditField_2Label = uilabel(app.UIFigure);
            app.PoundsofFertilizerUsedPerAcreEditField_2Label.Text = 'Pounds of Fertilizer Used Per Acre';
            app.PoundsofFertilizerUsedPerAcreEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.CompositionNitrogenEditField_3Label = uilabel(app.UIFigure);
            app.CompositionNitrogenEditField_3Label.Text = 'Composition Nitrogen';
            app.CompositionNitrogenEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.outputLabel = uilabel(app.UIFigure);
            app.outputLabel.Visible = 'off';
            app.outputLabel.Text = 'output';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'LAHacksArrow.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'LAHacksBorder.png');
            app.NitrogenRunOffCalculatorLabel = uilabel(app.UIFigure);
            app.NitrogenRunOffCalculatorLabel.Text = 'Nitrogen Run-Off Calculator';
            app.FeedbackLabel = uilabel(app.UIFigure);
            app.FeedbackLabel.Visible = 'off';
            app.FeedbackLabel.Text = 'Feedback';
            app.FactsAboutNitrogenPollutionButton = uibutton(app.UIFigure, 'push');
            app.FactsAboutNitrogenPollutionButton.ButtonPushedFcn = createCallbackFcn(app, @FactsAboutNitrogenPollutionButtonPushed, true);
            app.FactsAboutNitrogenPollutionButton.Text = 'Facts About Nitrogen Pollution';
            app.BacktoCalculatorButton = uibutton(app.UIFigure, 'push');
            app.BacktoCalculatorButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoCalculatorButtonPushed, true);
            app.BacktoCalculatorButton.Visible = 'off';
            app.BacktoCalculatorButton.Text = 'Back to Calculator';
            app.NitrogenPollutionThemostcommonnitrogencompoundsLabel = uilabel(app.UIFigure);
            app.NitrogenPollutionThemostcommonnitrogencompoundsLabel.Visible = 'off';
            app.NitrogenPollutionThemostcommonnitrogencompoundsLabel.Text = {'Nitrogen Pollution'; '- The most common nitrogen compounds to run-off from fertilizer are nitrate and ammonia'; '- Nitrogen run-off from fertilizer is the largest category of nitrogen pollution in the US'; '- Nitrogen pollution in our water can be dangerous because it is an excess of nutrients, this can cause'; ' algae blooms'; '- These algea blooms can be incredibly dangerous to other wildlife in the ecosystem because certain '; 'types of algae consume oxygen '; '- This can cause oxygen deprivation in the water which can ultimately lead to other wildlife drowing'; '- This is what causes the Red Tide on the Gulf Coast'; '- Nitrogen runoff can also be dangerous to us'; '- Nitrogen run-off and the different compunds can end up in our water sources and aquifers'; '- This can be dangerous to us because at high enough levels it becomes toxic'; '- By US department of health standards nitrate levels in drinking water must stay below 10 mg/L '};
            app.NitrogenCompositionLabel = uilabel(app.UIFigure);
            app.NitrogenCompositionLabel.Text = 'Nitrogen Composition';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.Visible = 'off';
            app.Hyperlink.URL = 'https://www.epa.gov/nutrientpollution/sources-and-solutions-agriculture';
            app.Hyperlink.Text = 'Fertilizer Run-off Reduction';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LAHacks2023App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end